classdef Conference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Play4Button    matlab.ui.control.Button
        Record4Button  matlab.ui.control.Button
        Play3Button    matlab.ui.control.Button
        Record3Button  matlab.ui.control.Button
        Play2Button    matlab.ui.control.Button
        Record2Button  matlab.ui.control.Button
        Play1Button    matlab.ui.control.Button
        Record1Button  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Record1Button
        function Record1ButtonPushed(app, event)
            recorder = audiorecorder(8000, 8, 1);
            
            disp("Start Recording");
            recordblocking(recorder, 4);
            disp("Stop Recording");
            pause(3)
            record1 = getaudiodata(recorder);
            audiowrite('Record1.wav', record1, 8000)

            plot(app.UIAxes, record1)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 1')
        end

        % Button pushed function: Record2Button
        function Record2ButtonPushed(app, event)
            recorder = audiorecorder(8000, 8, 1);
            
            disp("Start Recording");
            recordblocking(recorder, 4);
            disp("Stop Recording");
            pause(3)
            record2 = getaudiodata(recorder);
            audiowrite('Record2.wav', record2, 8000)

            plot(app.UIAxes, record2)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 2')
        end

        % Button pushed function: Record3Button
        function Record3ButtonPushed(app, event)
            recorder = audiorecorder(8000, 8, 1);
            
            disp("Start Recording");
            recordblocking(recorder, 4);
            disp("Stop Recording");
            pause(3)
            record3 = getaudiodata(recorder);
            audiowrite('Record3.wav', record3, 8000)

            plot(app.UIAxes, record3)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 3')
        end

        % Button pushed function: Record4Button
        function Record4ButtonPushed(app, event)
            recorder = audiorecorder(8000, 8, 1);

            disp("Start Recording");
            recordblocking(recorder, 4);
            disp("Stop Recording");
            pause(3)
            record4 = getaudiodata(recorder);
            audiowrite('Record4.wav', record4, 8000)

            plot(app.UIAxes, record4)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 4')
        end

        % Button pushed function: Play1Button
        function Play1ButtonPushed(app, event)
            [record2, Fs] = audioread('Record2.wav');
            [record3, Fs] = audioread('Record3.wav');
            [record4, Fs] = audioread('Record4.wav');

            sil = zeros(Fs * 1, size(record2, 2));

            final_voice = [record2; sil; record3; sil; record4];
            size(final_voice, 1)

            sound(final_voice, 8000)

            plot(app.UIAxes, final_voice)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 2 + 3 + 4')

        end

        % Button pushed function: Play2Button
        function Play2ButtonPushed(app, event)
            [record1, Fs] = audioread('Record1.wav');
            [record3, Fs] = audioread('Record3.wav');
            [record4, Fs] = audioread('Record4.wav');

            sil = zeros(Fs * 1, size(record1, 2));

            final_voice = [record1; sil; record3; sil; record4];

            sound(final_voice, 8000)
            size(final_voice, 1)

            plot(app.UIAxes, final_voice)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 1 + 3 + 4')
        end

        % Button pushed function: Play3Button
        function Play3ButtonPushed(app, event)
            [record1, Fs] = audioread('Record1.wav');
            [record2, Fs] = audioread('Record2.wav');
            [record4, Fs] = audioread('Record4.wav');

            sil = zeros(Fs * 1, size(record2, 2));

            final_voice = [record1; sil; record2; sil; record4];

            sound(final_voice, 8000)
            size(final_voice, 1)

            plot(app.UIAxes, final_voice)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 1 + 2 + 4')
        end

        % Button pushed function: Play4Button
        function Play4ButtonPushed(app, event)
            [record1, Fs] = audioread('Record1.wav');
            [record2, Fs] = audioread('Record2.wav');
            [record3, Fs] = audioread('Record3.wav');

            sil = zeros(Fs * 1, size(record2, 2));

            final_voice = [record1; sil; record2; sil; record3];

            sound(final_voice, 8000)
            size(final_voice, 1)

            plot(app.UIAxes, final_voice)
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            title(app.UIAxes, 'Record 1 + 2 + 3')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Record1Button = uibutton(app.UIFigure, 'push');
            app.Record1Button.ButtonPushedFcn = createCallbackFcn(app, @Record1ButtonPushed, true);
            app.Record1Button.Text = 'Record 1';
            app.Play1Button = uibutton(app.UIFigure, 'push');
            app.Play1Button.ButtonPushedFcn = createCallbackFcn(app, @Play1ButtonPushed, true);
            app.Play1Button.Text = 'Play 1';
            app.Record2Button = uibutton(app.UIFigure, 'push');
            app.Record2Button.ButtonPushedFcn = createCallbackFcn(app, @Record2ButtonPushed, true);
            app.Record2Button.Text = 'Record 2';
            app.Play2Button = uibutton(app.UIFigure, 'push');
            app.Play2Button.ButtonPushedFcn = createCallbackFcn(app, @Play2ButtonPushed, true);
            app.Play2Button.Text = 'Play 2';
            app.Record3Button = uibutton(app.UIFigure, 'push');
            app.Record3Button.ButtonPushedFcn = createCallbackFcn(app, @Record3ButtonPushed, true);
            app.Record3Button.Text = 'Record 3';
            app.Play3Button = uibutton(app.UIFigure, 'push');
            app.Play3Button.ButtonPushedFcn = createCallbackFcn(app, @Play3ButtonPushed, true);
            app.Play3Button.Text = 'Play 3';
            app.Record4Button = uibutton(app.UIFigure, 'push');
            app.Record4Button.ButtonPushedFcn = createCallbackFcn(app, @Record4ButtonPushed, true);
            app.Record4Button.Text = 'Record 4';
            app.Play4Button = uibutton(app.UIFigure, 'push');
            app.Play4Button.ButtonPushedFcn = createCallbackFcn(app, @Play4ButtonPushed, true);
            app.Play4Button.Text = 'Play 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Conference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end